classdef MitC_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MitC                            matlab.ui.Figure
        LoadButton                      matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        MessageWindow                   matlab.ui.control.TextArea
        NrsimulationsSpinnerLabel       matlab.ui.control.Label
        NrsimulationsSpinner            matlab.ui.control.Spinner
        RunsimulationButton             matlab.ui.control.Button
        TargetprojectdurationEditFieldLabel  matlab.ui.control.Label
        TargetprojectdurationEditField  matlab.ui.control.EditField
        Label_2                         matlab.ui.control.Label
        Lamp                            matlab.ui.control.Lamp
        MitigationcontrollerplannerLabel  matlab.ui.control.Label
        HoveryourcursorovereachbuttonformoreinformationLabel  matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        CloseButton                     matlab.ui.control.Button
    end

    
    properties (Access = private)
        path = addpath('bin','modules','plotting');
        Results
        Data
        Config = struct;       
        savefolder
        dataDouble
        dataCell        
    end
    
    methods (Access = private)              
        
        function [status, file] = check_user_input(app, file, currentFile)
            if ~ischar(file) % if the operation was cancelled
                if isempty(currentFile)
                    UpdateMessage(app, 'File selection aborted...');
                    set_lamp_red(app);
                    status = false;
                    file = [];
                elseif ~isempty(currentFile)
                    UpdateMessage(app, 'File selection aborted, keeping previous file');
                    set_lamp_orange(app)
                    status = true;
                    file = currentFile;                                    
                end
            else
                set_lamp_orange(app)       
                status = true;
            end
        end
        
        function status = check_input_type(app, file)  
            status = false;
            if ~isempty(file)
                [~,~,extension] = fileparts(file);
                switch lower(extension)
                    case '.xlsx'
                        set_lamp_orange(app)
                        status = true;
                    otherwise 
                        UpdateMessage(app, 'File type is not supported')
                        set_lamp_red(app)                          
                end
            else
                set_lamp_red(app)                
            end
        end
        
        function prepare_simulation(app)
            % Disable all user input during simulation                      
            disable_input(app);        
            UpdateMessage(app, 'Running simulation, please wait...');
        end
        
        function set_lamp_orange(app)
            app.Lamp.Color = [1 0.5 0];       
        end
        
        function set_lamp_green(app)
            app.Lamp.Color = [0 1 0];       
        end
        
        function set_lamp_red(app)
            app.Lamp.Color = [1 0 0];       
        end
        
        function disable_input(app)
            app.LoadButton.Enable = false;
            app.NrsimulationsSpinner.Enable = false;
            app.TargetprojectdurationEditField.Enable = false;
            app.SaveButton.Enable = false;
            app.RunsimulationButton.Enable = false;
        end
        
        function enable_input(app)
            app.LoadButton.Enable = true;
            app.NrsimulationsSpinner.Enable = true;
            app.TargetprojectdurationEditField.Enable = true;
            app.SaveButton.Enable = true;
            app.RunsimulationButton.Enable = true;
        end
        
        function check_run(app)
            if ~isempty(app.savefolder) && ~isempty(app.dataCell) &&~isempty(app.dataDouble)
                app.RunsimulationButton.Enable = true;
                set_lamp_green(app);
            else
                app.RunsimulationButton.Enable = false;
                set_lamp_orange(app);
            end
        end
                
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: MessageWindow
        function UpdateMessage(app, event)
            value = app.MessageWindow.Value;
            app.MessageWindow.Value = [value; event];  
        end

        % Button pushed function: LoadButton
        function load_data(app, event)
                   
            % Check whether a file is already imported
            if ~isfield(app.Config,'file')
                currentFile = [];
            elseif ischar(app.Config.file)
                currentFile = app.Config.file;
            else
                currentFile = [];
            end
            
            % Ask user for input file
            [filename, pathname] = uigetfile('../data/*.xlsx', 'Select your project data');       
            file = [pathname filename];     
            
            [inputStatus, file] = check_user_input(app, file, currentFile);                       
                               
            % Verify input data type 
            inputTypeStatus = check_input_type(app, file);               
            
            if inputStatus && inputTypeStatus
                % Import project data           
                [app.dataDouble, app.dataCell] = import_project(file);   
                UpdateMessage(app,['Succesfully loaded: ', file]);
                set_lamp_green(app);
                check_run(app);                
            else
                UpdateMessage(app, 'No file selected');
                set_lamp_red(app);
                return
            end
            
            % Store imported file
            app.Config.file = file;
            
            % Parse and verify data
            app.Data = parse_data(app.dataDouble, app.dataCell);
            UpdateMessage(app, 'Input data imported');
        end

        % Button pushed function: RunsimulationButton
        function run_simulation(app, event)
            prepare_simulation(app);    
        
            % Get user settings
            nsimulations = app.NrsimulationsSpinner.Value;
            targetProjectDuration = str2double(app.TargetprojectdurationEditField.Value);
                                 
            % Generate matrix with all paths
            [app.Data.P_ki, app.Data.A] = fill_path_matrix(app.Data.R_ii, app.Data.nActivities);
            
            % Select critical paths to reduce the simulation time
            [app.Data.T_orig, app.Data.P_cr_0, app.Data.K] = ...
                         select_critical_paths(...                         
                            app.Data.P_ki,...
                            app.Data.durationActivities,...
                            app.Data.riskEventsDuration,...
                            app.Data.E_ie);
                        
            % Run simulation
            app.Results = mitc_simulation(app.Data, nsimulations, targetProjectDuration);
            UpdateMessage(app, 'Simulation finished');
            
            UpdateMessage(app, 'Generating plots...');
            % Generate plots
            plot_network(app.Data.A, app.dataCell);
%             plot_frequency_mitigation(app.Results, app.Data, nsimulations);
                        
            
            UpdateMessage(app, 'Saving...');
            % Save data and plots
%             save_data()

            UpdateMessage(app, 'Finished!');
            set_lamp_green(app);
            
        end

        % Button pushed function: SaveButton
        function save_output(app, event)
            app.savefolder = uigetdir('../results','Please select a directory');     
            UpdateMessage(app, ['Selected output folder: ', app.savefolder]);
            check_run(app);  
        end

        % Button pushed function: ResetButton
        function reset(app, event)
            enable_input(app);
            app.RunsimulationButton.Enable = false;
            set_lamp_orange(app);
            app.MessageWindow.Value = {'Version 0.1.0'};
            app.Data = [];
            app.Results = [];
            app.dataCell = [];
            app.dataDouble = [];
            app.savefolder = [];            
            
        end

        % Button pushed function: CloseButton
        function close(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MitC = uifigure('Visible', 'off');
            app.MitC.Name = 'UI Figure';
            app.LoadButton = uibutton(app.MitC, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @load_data, true);
            app.LoadButton.Tooltip = {'Select a single project data file. Currently only .xlsx format is supported.'};
            app.LoadButton.Text = 'Load project data (.xlsx)';
            app.Label = uilabel(app.MitC);
            app.Label.Text = '';
            app.MessageWindow = uitextarea(app.MitC);
            app.MessageWindow.ValueChangedFcn = createCallbackFcn(app, @UpdateMessage, true);
            app.MessageWindow.Editable = 'off';
            app.MessageWindow.Value = {'Version 0.1.0'};
            app.NrsimulationsSpinnerLabel = uilabel(app.MitC);
            app.NrsimulationsSpinnerLabel.Text = 'Nr. simulations';
            app.NrsimulationsSpinner = uispinner(app.MitC);
            app.NrsimulationsSpinner.Step = 10;
            app.NrsimulationsSpinner.Limits = [1 10000];
            app.NrsimulationsSpinner.Tooltip = {'Info to be added'};
            app.NrsimulationsSpinner.Value = 500;
            app.RunsimulationButton = uibutton(app.MitC, 'push');
            app.RunsimulationButton.ButtonPushedFcn = createCallbackFcn(app, @run_simulation, true);
            app.RunsimulationButton.Enable = 'off';
            app.RunsimulationButton.Tooltip = {'Info to be added'};
            app.RunsimulationButton.Text = 'Run simulation';
            app.TargetprojectdurationEditFieldLabel = uilabel(app.MitC);
            app.TargetprojectdurationEditFieldLabel.Text = 'Target project duration';
            app.TargetprojectdurationEditField = uieditfield(app.MitC, 'text');
            app.TargetprojectdurationEditField.Tooltip = {'Info to be added'};
            app.TargetprojectdurationEditField.Value = '1466';
            app.Label_2 = uilabel(app.MitC);
            app.Label_2.Text = '';
            app.Lamp = uilamp(app.MitC);
            app.Lamp.Tooltip = {'Info to be added'};
            app.MitigationcontrollerplannerLabel = uilabel(app.MitC);
            app.MitigationcontrollerplannerLabel.Text = 'Mitigation controller planner';
            app.HoveryourcursorovereachbuttonformoreinformationLabel = uilabel(app.MitC);
            app.HoveryourcursorovereachbuttonformoreinformationLabel.Text = 'Hover your cursor over each button for more information. ';
            app.SaveButton = uibutton(app.MitC, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @save_output, true);
            app.SaveButton.Tooltip = {'Info to be added'};
            app.SaveButton.Text = 'Select save folder';
            app.ResetButton = uibutton(app.MitC, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @reset, true);
            app.ResetButton.Tooltip = {'Info to be added'};
            app.ResetButton.Text = 'Reset';
            app.CloseButton = uibutton(app.MitC, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @close, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.MitC.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MitC_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MitC)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MitC)
        end
    end
end